classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ALiveLabel           matlab.ui.control.Label
        LoadButton           matlab.ui.control.Button
        xDataEditField       matlab.ui.control.EditField
        xDataEditFieldLabel  matlab.ui.control.Label
        nDataEditField       matlab.ui.control.EditField
        nDataEditFieldLabel  matlab.ui.control.Label
        iDEditField          matlab.ui.control.EditField
        iDEditFieldLabel     matlab.ui.control.Label
        SendButton           matlab.ui.control.Button
        OpenButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        s % Description
        iD string % Description
        nData string % Description
        xData string % Description
    end
    
    methods (Access = private)
        
        function SerialPortCallBack010(app, ~, ~)% only one switch for Id
            try
                [iD, nData, xData] = readDataSTM32(app.s);
                switch iD
                case 1010
                    app.ALiveLabel.Text = string(xData);
                    % toc; tic;
                case 11
                    if nData>0
                        % plot_x(xData);
                        %figure();
                        %plot(xData)
                    end
                    case 20
                    app.iDEditField.Value = iD;
                    app.nDataEditField.Value = nData;
                    app.xDataEditField.Value = xData;
                end
            catch ME
                disp(ME.message) % rethrow(ME)
            end
        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            try
                   
                app.s = serialport('/dev/ttyACM1', 2*115200);
                flush(app.s);
                %configureCallback(app.s ,"byte", 4, @app.SerialPortCallBack011);
                configureCallback(app.s ,"byte", 4, @app.SerialPortCallBack010);
                % configureCallback(app.s ,"char", 10, @app.SerialPortCallBack011);
            catch ME
                disp(ME.message)
                % rethrow(ME)
            end 
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
                
            x = 1:10;
            writeDataSTM32(app.s, 20, 10, x);
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [app.iD, app.nData, app.xData] = readDataSTM32(app.s);

            app.iDEditField.Value = app.iD;
            app.nDataEditField.Value = app.nData;
            app.xDataEditField.Value = app.xData;
        end

        % Value changing function: iDEditField
        function iDEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.SendButton = uibutton(app.UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.iDEditFieldLabel = uilabel(app.UIFigure);
            app.iDEditFieldLabel.Text = 'iD';
            app.iDEditField = uieditfield(app.UIFigure, 'text');
            app.iDEditField.ValueChangingFcn = createCallbackFcn(app, @iDEditFieldValueChanging, true);
            app.nDataEditFieldLabel = uilabel(app.UIFigure);
            app.nDataEditFieldLabel.Text = 'nData';
            app.nDataEditField = uieditfield(app.UIFigure, 'text');
            app.xDataEditFieldLabel = uilabel(app.UIFigure);
            app.xDataEditFieldLabel.Text = 'xData';
            app.xDataEditField = uieditfield(app.UIFigure, 'text');
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ALiveLabel = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end